classdef eComp_CICD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        S01_Panel_4              matlab.ui.container.Panel
        S01_BT_UpdataInterface   matlab.ui.control.Button
        S01_BT_LoadInterface     matlab.ui.control.Button
        S01_LB_InterfaceSet      matlab.ui.control.Label
        InterfaceSettingsLabel   matlab.ui.control.Label
        S01_Panel_3              matlab.ui.container.Panel
        S01_BT_SimParamSet       matlab.ui.control.Button
        S01_LB_SimSetParam       matlab.ui.control.Label
        SimulationParameterSettingsLabel  matlab.ui.control.Label
        AutoCodeGenerationLabel  matlab.ui.control.Label
        S01_Panel_2              matlab.ui.container.Panel
        S01_BT_CompareCode1_4    matlab.ui.control.Button
        S01_BT_CompareCode1_3    matlab.ui.control.Button
        S01_BT_CompareCode1_2    matlab.ui.control.Button
        S01_BT_CompareCode1      matlab.ui.control.Button
        SelectSWCLabel           matlab.ui.control.Label
        S01_TreeSelCode          matlab.ui.container.CheckBoxTree
        SelectSWCNode            matlab.ui.container.TreeNode
        b01_OSnSchedulerNode     matlab.ui.container.TreeNode
        c01_MainAdcNode          matlab.ui.container.TreeNode
        c02_SubAdcNode           matlab.ui.container.TreeNode
        d01_DiaglntegNode        matlab.ui.container.TreeNode
        e01_MtrCtrl_VarGainNode  matlab.ui.container.TreeNode
        e02_MtrCtrl_EstPstNode   matlab.ui.container.TreeNode
        e03_MtrCtrl_MtrCtrlNode  matlab.ui.container.TreeNode
        e04_MtrCtrl_PwmSetNode   matlab.ui.container.TreeNode
        f01_CommRx1Node          matlab.ui.container.TreeNode
        g01_CommTxDataSet1Node   matlab.ui.container.TreeNode
        g02_CommTxDataSet2Node   matlab.ui.container.TreeNode
        g03_CommTxNode           matlab.ui.container.TreeNode
        f11_CanRx1Node           matlab.ui.container.TreeNode
        g11_CanTxDataSet1Node    matlab.ui.container.TreeNode
        g12_CanTxDataSet2Node    matlab.ui.container.TreeNode
        g13_CanTxNode            matlab.ui.container.TreeNode
        h01_MdeMgmtNode          matlab.ui.container.TreeNode
        i01_DemNode              matlab.ui.container.TreeNode
        j01_McuInitNode          matlab.ui.container.TreeNode
        k01_NvMNode              matlab.ui.container.TreeNode
        Label                    matlab.ui.control.Label
        S01_LB_GenCode           matlab.ui.control.Label
        S01_BT_GenCod            matlab.ui.control.Button
        InterfaceandParameterSettingsLabel  matlab.ui.control.Label
        S01_Panel_1              matlab.ui.container.Panel
        S01_BT_ParamSet          matlab.ui.control.Button
        S01_BT_ImportDBFile      matlab.ui.control.Button
        S01_LB_SetParam          matlab.ui.control.Label
        LB_GuideText_2           matlab.ui.control.Label
        eCOMPSWCICDAppLabel      matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % assignin('base','ST', ST);
    % load('aMain_VehNr_01.mat');
    % run('Step08_Plot');
    % writetable(app.GVS01_EditElecMagntDefine, GMS01.SaveFilePathName,'Sheet', 'Sheet01_ElecMagntDefine', 'Range', 'A1','PreserveFormat',true);

    properties (Access = private)
        S01_FileName
        S01_Path
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: S01_BT_GenCod
        function S01_BT_GenCodButtonPushed(app, event)
            %% mlx 실행 오류 방지를 위한 변수 설정 (mlx실행 시 오류가 없으나, App으로 실한한 경우 하기 선언이 없다는 오류 발생)
            OutFile = 'ExportInterface.xlsx';
            InFile = 'SWC_Info.xlsx';

            %% Visible 설정
            app.S01_LB_GenCode.Visible = "on";

            [Row Column] = size(app.S01_TreeSelCode.CheckedNodes);

            if(Row == 0)
                app.S01_LB_GenCode.Text = 'SWC 가 선택되지 않았습니다.';
                msgbox("SWC 가 선택되지 않았습니다.");
            else
                % Check Tree 항목 확인 및 코드 생성
                app.S01_LB_GenCode.Text = '코드 생성 중...';

                % progressbar 초기 설정
                progressbar('Generating Code');
                drawnow;
                progressbar(0); 
                GenCodePrcsbr.n = 0;
                GenCodePrcsbr.g = 100 / Row;
    
                for i=1:Row
                    MdlName = app.S01_TreeSelCode.CheckedNodes(i).Text;  a02_Sub_CodeGen;
    
                    GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;
                    progressbar(GenCodePrcsbr.n * 0.01);
                end
    
                % run('a01_Main_GenCode');
    
                clear GenCodePrcsbr;
                progressbar(); clear GenCodePrcsbr;
                app.S01_LB_GenCode.Text = '코드 생성 완료';
                msgbox("코드 생성이 완료되었습니다.");
            end
        end

        % Clicked callback: S01_TreeSelCode
        function S01_TreeSelCodeClicked(app, event)
            node = event.InteractionInformation.Node;
            app.S01_TreeSelCode.expand;

            app.S01_LB_GenCode.Visible = "on";
            app.S01_LB_GenCode.Text = '생성할 코드를 선택하세요.';
        end

        % Button pushed function: S01_BT_ImportDBFile
        function S01_BT_ImportDBFileButtonPushed(app, event)
            % 파일 선택
            [app.S01_FileName, app.S01_Path] = uigetfile({'*.xlsx'},'Select a file','ASW\Sources\CtrlParam');

            % 엑셀 파일 Open
            winopen(app.S01_FileName);

            % Text Display
            app.S01_LB_SetParam.Visible = "on";
            app.S01_LB_SetParam.Text = 'Data를 수정한 후 저장하십시오.';
        end

        % Button pushed function: S01_BT_ParamSet
        function S01_BT_ParamSetButtonPushed(app, event)
            % Matlab base에서 mlx 파일 실행
            % Text Display
            app.S01_LB_SetParam.Visible = "on";
            app.S01_LB_SetParam.Text = {'엑셀 파일을 선택하고', '약 20초 동안 대기하십시오.'};

            evalin('base', 'run("a01_Main.mlx")');

             % Text Display
            app.S01_LB_SetParam.Text = 'Data를 작업공간에 저장하였습니다.';   
        end

        % Button pushed function: S01_BT_LoadInterface
        function S01_BT_LoadInterfaceButtonPushed(app, event)
            %% Visible 설정
            app.S01_LB_InterfaceSet.Visible = "on";
            app.S01_LB_InterfaceSet.Text = 'Data를 수정한 후 저장하십시오.';

            % 엑셀 파일 Open
            winopen('ExportInterface.xlsx');
        end

        % Button pushed function: S01_BT_UpdataInterface
        function S01_BT_UpdataInterfaceButtonPushed(app, event)
            %% Visible 설정
            app.S01_LB_InterfaceSet.Visible = "on";
            app.S01_LB_InterfaceSet.Text = 'Interface 업데이트 중...';

            InFile = 'ExportInterface.xlsx';

            % progressbar 초기 설정
            progressbar('Update Interface');
            drawnow;
            progressbar(0); 
            GenCodePrcsbr.n = 0;
            GenCodePrcsbr.g = 100 / 16;         % 모델 개수 16

            MdlName = 'b01_OSnScheduler';       Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'c01_MainAdc';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'c02_SubAdc';             Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'd01_Diaglnteg';          Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e01_MtrCtrl_VarGain';    Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e02_MtrCtrl_EstPst';     Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e03_MtrCtrl_MtrCtrl';    Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'e04_MtrCtrl_PwmSet';     Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'f01_CommRx1';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'g01_CommTxDataSet1';     Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'g02_CommTxDataSet2';     Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'g03_CommTx';             Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'h01_MdeMgmt';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'i01_Dem';                Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'j01_McuInit';            Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);
            MdlName = 'k01_NvM';                Ftn_SetFunctionInfo(MdlName, InFile);   GenCodePrcsbr.n = GenCodePrcsbr.n + GenCodePrcsbr.g;    progressbar(GenCodePrcsbr.n * 0.01);

            clear GenCodePrcsbr;
            progressbar(); clear GenCodePrcsbr;
            app.S01_LB_InterfaceSet.Text = 'Interface 업데이트 완료';
            msgbox("Interface 업데이트가 완료되었습니다.");

            % evalin('base', 'run("a03_Sub_UpdateInterface.mlx")');
        end

        % Button pushed function: S01_BT_SimParamSet
        function S01_BT_SimParamSetButtonPushed(app, event)
            evalin('base', 'run("a08_SimVariable.mlx")');
            evalin('base', 'run("a09_SimSetting.mlx")');
            app.S01_LB_SimSetParam.Text = {'시뮬레이션 Parameter 설정이', '완료되었습니다.'};
            msgbox("시뮬레이션 Parameter 설정이 완료되었습니다.");
        end

        % Button pushed function: S01_BT_CompareCode1
        function S01_BT_CompareCode1ButtonPushed(app, event)
            try
                system('"C:\Program Files\Beyond Compare 5\BCompare.exe" ASW\Codegen ASW\eCompMbdCodeRlt');
            catch
                system('"C:\Program Files\Beyond Compare 4\BCompare.exe" ASW\Codegen ASW\eCompMbdCodeRlt');
                try
                    system('"C:\Program Files\WinMerge\WinMergeU.exe" ASW\Codegen ASW\eCompMbdCodeRlt');
                catch
                    msgbox("Beyond Compare 또는 WinMerge를 설치해야 합니다.");
                end                
            end
        end

        % Button pushed function: S01_BT_CompareCode1_2
        function S01_BT_CompareCode1_2ButtonPushed(app, event)
            mDefine_local = evalin('base', 'mDefine');

            if(mDefine_local.uVehicle_Num == 1)                             % SW EV 45cc 400V
                try
                    system('"C:\Program Files\Beyond Compare 5\BCompare.exe" ASW\eCompMbdCodeRlt ASW\eCompMbdCodeRlt_SW');
                catch
                    system('"C:\Program Files\Beyond Compare 4\BCompare.exe" ASW\eCompMbdCodeRlt ASW\eCompMbdCodeRlt_SW');
                    try
                        system('"C:\Program Files\WinMerge\WinMergeU.exe" ASW\eCompMbdCodeRlt ASW\eCompMbdCodeRlt_SW');
                    catch
                        msgbox("Beyond Compare 또는 WinMerge를 설치해야 합니다.");
                    end                
                end
            elseif(mDefine_local.uVehicle_Num == 2)                         % TV EV 45CC 800V

            elseif(mDefine_local.uVehicle_Num == 3)
                if(mDefine_local.uIgbtTempSensType == 3)                    % OV1 ONSEMI EV 34CC 400V     

                elseif(mDefine_local.uIgbtTempSensType == 5)                % OV1 ROHM EV 34CC 400V
                    try
                        system('"C:\Program Files\Beyond Compare 5\BCompare.exe" ASW\eCompMbdCodeRlt ASW\eCompMbdCodeRlt_OV1_R');
                    catch
                        system('"C:\Program Files\Beyond Compare 4\BCompare.exe" ASW\eCompMbdCodeRlt ASW\eCompMbdCodeRlt_OV1_R');
                        try
                            system('"C:\Program Files\WinMerge\WinMergeU.exe" ASW\eCompMbdCodeRlt ASW\eCompMbdCodeRlt_OV1_R');
                        catch
                            msgbox("Beyond Compare 또는 WinMerge를 설치해야 합니다.");
                        end                
                    end
                end
            elseif(mDefine_local.uVehicle_Num == 4)                         % TV EV 45CC 800V                     
                
            end
        end

        % Button pushed function: S01_BT_CompareCode1_4
        function S01_BT_CompareCode1_4ButtonPushed(app, event)
            evalin('base', 'run("s01_OpenProjectSub.mlx")');
            msgbox("코드를 복사하였습니다. (Storage ▶ Rlt)");
        end

        % Button pushed function: S01_BT_CompareCode1_3
        function S01_BT_CompareCode1_3ButtonPushed(app, event)
            evalin('base', 'run("s02_CopyRlt2Storage.mlx")');
            msgbox("코드를 복사하였습니다. (Rlt ▶ Storage)");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.eCOMPSWCICDAppLabel = uilabel(app.UIFigure);
            app.eCOMPSWCICDAppLabel.Text = '   eCOMP S/W CI/CD App.';
            app.LB_GuideText_2 = uilabel(app.UIFigure);
            app.LB_GuideText_2.Text = 'DOOWON Climate Control Co., Ltd.';
            app.S01_Panel_1 = uipanel(app.UIFigure);
            app.S01_LB_SetParam = uilabel(app.S01_Panel_1);
            app.S01_LB_SetParam.Text = 'Message';
            app.S01_BT_ImportDBFile = uibutton(app.S01_Panel_1, 'push');
            app.S01_BT_ImportDBFile.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_ImportDBFileButtonPushed, true);
            app.S01_BT_ImportDBFile.Text = '  Open Data Dictionary';
            app.S01_BT_ParamSet = uibutton(app.S01_Panel_1, 'push');
            app.S01_BT_ParamSet.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_ParamSetButtonPushed, true);
            app.S01_BT_ParamSet.Text = '  Prameter Settings';
            app.InterfaceandParameterSettingsLabel = uilabel(app.UIFigure);
            app.InterfaceandParameterSettingsLabel.Text = '■ Interface and Parameter Settings';
            app.S01_Panel_2 = uipanel(app.UIFigure);
            app.S01_BT_GenCod = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_GenCod.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_GenCodButtonPushed, true);
            app.S01_BT_GenCod.Text = '  Gode Generation';
            app.S01_LB_GenCode = uilabel(app.S01_Panel_2);
            app.S01_LB_GenCode.Text = 'Message';
            app.Label = uilabel(app.S01_Panel_2);
            app.Label.Text = '  ';
            app.S01_TreeSelCode = uitree(app.S01_Panel_2, 'checkbox');
            app.S01_TreeSelCode.ClickedFcn = createCallbackFcn(app, @S01_TreeSelCodeClicked, true);
            app.SelectSWCNode = uitreenode(app.S01_TreeSelCode);
            app.SelectSWCNode.Text = 'Select SWC';
            app.b01_OSnSchedulerNode = uitreenode(app.SelectSWCNode);
            app.b01_OSnSchedulerNode.Text = 'b01_OSnScheduler';
            app.c01_MainAdcNode = uitreenode(app.SelectSWCNode);
            app.c01_MainAdcNode.Text = 'c01_MainAdc';
            app.c02_SubAdcNode = uitreenode(app.SelectSWCNode);
            app.c02_SubAdcNode.Text = 'c02_SubAdc';
            app.d01_DiaglntegNode = uitreenode(app.SelectSWCNode);
            app.d01_DiaglntegNode.Text = 'd01_Diaglnteg';
            app.e01_MtrCtrl_VarGainNode = uitreenode(app.SelectSWCNode);
            app.e01_MtrCtrl_VarGainNode.Text = 'e01_MtrCtrl_VarGain';
            app.e02_MtrCtrl_EstPstNode = uitreenode(app.SelectSWCNode);
            app.e02_MtrCtrl_EstPstNode.Text = 'e02_MtrCtrl_EstPst';
            app.e03_MtrCtrl_MtrCtrlNode = uitreenode(app.SelectSWCNode);
            app.e03_MtrCtrl_MtrCtrlNode.Text = 'e03_MtrCtrl_MtrCtrl';
            app.e04_MtrCtrl_PwmSetNode = uitreenode(app.SelectSWCNode);
            app.e04_MtrCtrl_PwmSetNode.Text = 'e04_MtrCtrl_PwmSet';
            app.f01_CommRx1Node = uitreenode(app.SelectSWCNode);
            app.f01_CommRx1Node.Text = 'f01_CommRx1';
            app.g01_CommTxDataSet1Node = uitreenode(app.SelectSWCNode);
            app.g01_CommTxDataSet1Node.Text = 'g01_CommTxDataSet1';
            app.g02_CommTxDataSet2Node = uitreenode(app.SelectSWCNode);
            app.g02_CommTxDataSet2Node.Text = 'g02_CommTxDataSet2';
            app.g03_CommTxNode = uitreenode(app.SelectSWCNode);
            app.g03_CommTxNode.Text = 'g03_CommTx';
            app.f11_CanRx1Node = uitreenode(app.SelectSWCNode);
            app.f11_CanRx1Node.Text = 'f11_CanRx1';
            app.g11_CanTxDataSet1Node = uitreenode(app.SelectSWCNode);
            app.g11_CanTxDataSet1Node.Text = 'g11_CanTxDataSet1';
            app.g12_CanTxDataSet2Node = uitreenode(app.SelectSWCNode);
            app.g12_CanTxDataSet2Node.Text = 'g12_CanTxDataSet2';
            app.g13_CanTxNode = uitreenode(app.SelectSWCNode);
            app.g13_CanTxNode.Text = 'g13_CanTx';
            app.h01_MdeMgmtNode = uitreenode(app.SelectSWCNode);
            app.h01_MdeMgmtNode.Text = 'h01_MdeMgmt';
            app.i01_DemNode = uitreenode(app.SelectSWCNode);
            app.i01_DemNode.Text = 'i01_Dem';
            app.j01_McuInitNode = uitreenode(app.SelectSWCNode);
            app.j01_McuInitNode.Text = 'j01_McuInit';
            app.k01_NvMNode = uitreenode(app.SelectSWCNode);
            app.k01_NvMNode.Text = 'k01_NvM';
            app.SelectSWCLabel = uilabel(app.S01_Panel_2);
            app.SelectSWCLabel.Text = 'Select SWC';
            app.S01_BT_CompareCode1 = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_CompareCode1.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_CompareCode1ButtonPushed, true);
            app.S01_BT_CompareCode1.Text = '  Compare Code (Codegen VS Rlt)';
            app.S01_BT_CompareCode1_2 = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_CompareCode1_2.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_CompareCode1_2ButtonPushed, true);
            app.S01_BT_CompareCode1_2.Text = '  Compare Code (Rlt VS Storage)';
            app.S01_BT_CompareCode1_3 = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_CompareCode1_3.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_CompareCode1_3ButtonPushed, true);
            app.S01_BT_CompareCode1_3.Text = '  Copy Code (Rlt ▶ Storage)';
            app.S01_BT_CompareCode1_4 = uibutton(app.S01_Panel_2, 'push');
            app.S01_BT_CompareCode1_4.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_CompareCode1_4ButtonPushed, true);
            app.S01_BT_CompareCode1_4.Enable = 'off';
            app.S01_BT_CompareCode1_4.Text = '  Copy Code (Storage ▶ Rlt)';
            app.AutoCodeGenerationLabel = uilabel(app.UIFigure);
            app.AutoCodeGenerationLabel.Text = '■ Auto Code Generation';
            app.SimulationParameterSettingsLabel = uilabel(app.UIFigure);
            app.SimulationParameterSettingsLabel.Text = '■ Simulation Parameter Settings';
            app.S01_Panel_3 = uipanel(app.UIFigure);
            app.S01_LB_SimSetParam = uilabel(app.S01_Panel_3);
            app.S01_LB_SimSetParam.Text = 'Message';
            app.S01_BT_SimParamSet = uibutton(app.S01_Panel_3, 'push');
            app.S01_BT_SimParamSet.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_SimParamSetButtonPushed, true);
            app.S01_BT_SimParamSet.Text = '  Parameter Settings';
            app.InterfaceSettingsLabel = uilabel(app.UIFigure);
            app.InterfaceSettingsLabel.Text = '■ Interface Settings';
            app.S01_Panel_4 = uipanel(app.UIFigure);
            app.S01_LB_InterfaceSet = uilabel(app.S01_Panel_4);
            app.S01_LB_InterfaceSet.Text = 'Message';
            app.S01_BT_LoadInterface = uibutton(app.S01_Panel_4, 'push');
            app.S01_BT_LoadInterface.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_LoadInterfaceButtonPushed, true);
            app.S01_BT_LoadInterface.Text = '  Load Model Interface';
            app.S01_BT_UpdataInterface = uibutton(app.S01_Panel_4, 'push');
            app.S01_BT_UpdataInterface.ButtonPushedFcn = createCallbackFcn(app, @S01_BT_UpdataInterfaceButtonPushed, true);
            app.S01_BT_UpdataInterface.Text = '  Update Model Interface';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eComp_CICD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end